classdef AudioSignallll < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BrowseAudioButton           matlab.ui.control.Button
        PlayAudioButton             matlab.ui.control.Button
        SelectChannelDropDownLabel  matlab.ui.control.Label
        SelectChannelDropDown       matlab.ui.control.DropDown
        ApplyChannelButton          matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        NoiseStandardDeviationEditFieldLabel  matlab.ui.control.Label
        NoiseStandardDeviationEditField  matlab.ui.control.NumericEditField
        AddNoiseButton              matlab.ui.control.Button
        PlayNoisyAudioButton        matlab.ui.control.Button
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        ApplyLowPassFilterButton    matlab.ui.control.Button
        PlayFilteredAudioButton     matlab.ui.control.Button
        UIAxes7                     matlab.ui.control.UIAxes
        UIAxes8                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end

       properties (Access = private)
        audioData 
        fs
        filename
        channelOutput 
        noisySignal
        filteredSignal
        
    end


    methods (Access = private)

        % Button pushed function: BrowseAudioButton
        function BrowseAudioButtonPushed(app, event)
    [file, path] = uigetfile({'*.wav;*.mp3; Audio Files (*.wav, *.mp3)'}, 'Select Audio File');
    if isequal(file, 0)
        return;
    end
    app.filename = fullfile(path, file);
    [app.audioData, app.fs] = audioread(app.filename);
    
    
    % Plot time domain
    t = (0:length(app.audioData)-1) / app.fs;
    plot(app.UIAxes, t, app.audioData);
    title(app.UIAxes, 'Time Domain');
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Amplitude');

    % Plot frequency domain
    L = length(app.audioData);
    f = linspace(-app.fs/2, app.fs/2, L);
    Y = fftshift(abs(fft(app.audioData)));
    plot(app.UIAxes2, f, Y);
    title(app.UIAxes2, 'Frequency Domain');
    xlabel(app.UIAxes2, 'Frequency (Hz)');
    ylabel(app.UIAxes2, 'Magnitude');
  

        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
           if isempty(app.audioData)
        uialert(app.UIFigure, 'No audio file loaded.', 'Error');
        return;
    end
    sound(app.audioData, app.fs);
        end

        % Button pushed function: ApplyChannelButton
        function ApplyChannelButtonPushed(app, event)
             if isempty(app.audioData)
        uialert(app.UIFigure, 'No audio data to process.', 'Error');
        return;
    end
    fs = app.fs;
    t = (0:length(app.audioData)-1) / fs;
    chType = app.SelectChannelDropDown.Value;

    % Define impulse response
    switch chType
        case 'Delta (No Effect)'
            h = 1;
        case 'Exp: e^(-2*pi*5000*t)'
            h = exp(-2*pi*5000*t);
        case 'Exp: e^(-2*pi*1000*t)'
            h = exp(-2*pi*1000*t);
        case 'Sinc: sinc(2*3000*t)' 
            x=2*3000*t;  
            h = ones(size(x)); 
            nonZero=(x~=0);
            h(nonZero)=sin(pi*x(nonZero))./(pi*x(nonZero)); 
             
        case 'Custom Channel'
            % Example: a custom impulse response
            h = [2 zeros(1,fs-2) 0.5 ];
          
            
           end   
      
    if size(app.audioData, 2) > 1
    app.audioData = mean(app.audioData, 2);
end
    % Apply convolution
    app.channelOutput = conv(app.audioData, h,'same');

    % Plot time domain
    t_new = (0:length(app.channelOutput)-1) / app.fs;
    plot(app.UIAxes3, t_new, app.channelOutput);
    title(app.UIAxes3, ' Time Domain');
    xlabel(app.UIAxes3, 'Time (s)');
    ylabel(app.UIAxes3, 'Amplitude');

    % Plot frequency domain
    Y = fftshift(abs(fft(app.channelOutput)));
    f = linspace(-app.fs/2, app.fs/2, length(Y));
    plot(app.UIAxes4, f, Y);
    title(app.UIAxes4, '   Frequency Domain');
    xlabel(app.UIAxes4, 'Frequency (Hz)');
    ylabel(app.UIAxes4, 'Magnitude');
  

        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            if isempty(app.channelOutput)
        uialert(app.UIFigure, 'No channel output found.', 'Error');
        return;
    end

    sigma = app.NoiseStandardDeviationEditField.Value;
    noise = sigma * randn(size(app.channelOutput));
    app.noisySignal = app.channelOutput + noise;

    % Plot time domain
    t = (0:length(app.noisySignal)-1) / app.fs;
    plot(app.UIAxes5, t, app.noisySignal);
    title(app.UIAxes5, ' Time Domain');
    xlabel(app.UIAxes5, 'Time (s)');
    ylabel(app.UIAxes5, 'Amplitude');

    % Plot frequency domain
    Y = fftshift(abs(fft(app.noisySignal)));
    f = linspace(-app.fs/2, app.fs/2, length(Y));
    plot(app.UIAxes6, f, Y);
    title(app.UIAxes6, '  Frequency Domain');
    xlabel(app.UIAxes6, 'Frequency (Hz)');
    ylabel(app.UIAxes6, 'Magnitude');
 
        end

        % Button pushed function: PlayNoisyAudioButton
        function PlayNoisyAudioButtonPushed(app, event)
             if isempty(app.noisySignal)
        uialert(app.UIFigure, 'No noisy signal found.', 'Error');
        return;
    end
    sound(app.noisySignal, app.fs);
        end

        % Button pushed function: ApplyLowPassFilterButton
        function ApplyLowPassFilterButtonPushed(app, event)
x = app.noisySignal;  % Input noisy signal
fs = app.fs;          % Sampling frequency
fc = 3400;            % Cutoff frequency for ideal low-pass filter
N = length(x);        % Number of samples in the signal

X = fft(x);           % Apply FFT to get frequency domain representation


f = (0:N-1)*(fs/N);       % Frequency vector from 0 to fs
f_neg = f - fs;           % Negative frequency part ( around to represent -fs to 0)

% Filter positive frequencies (0 to fs)
X_pos = X;
X_pos(f > fc ) = 0;

% Filter negative frequencies (-fs to 0)
X_neg = X;
X_neg(f_neg < -fc) = 0;
          % Zero out frequencies below -cutoff

mask_pos = (f <= fs/2).';   % Column vector mask for positive frequencies
mask_neg = (f_neg > -fs/2).';    % Column vector mask for negative frequencies

% Combine the two filtered frequency components
X_filtered = X_pos.*mask_pos + X_neg.*mask_neg;
% Convert back to time domain using IFFT
y_filtered = real(ifft(X_filtered));
app.filteredSignal = y_filtered;


% ======= Plot in Time Domain =======
t = (0:N-1)/fs;  % Time vector
plot(app.UIAxes7, t, y_filtered);
title(app.UIAxes7, '  Time Domain');
xlabel(app.UIAxes7, 'Time (s)');
ylabel(app.UIAxes7, 'Amplitude');

% ======= Plot in Frequency Domain =======
Y_mag = abs(fftshift(X_filtered));    % Magnitude spectrum (shifted for centered view)
f_axis = linspace(-fs/2, fs/2, N);    % Frequency axis from -fs/2 to fs/2
plot(app.UIAxes8, f_axis, Y_mag);
title(app.UIAxes8, ' Frequency Domain');
xlabel(app.UIAxes8, 'Frequency (Hz)');
ylabel(app.UIAxes8, 'Magnitude');
figure;
plot( f_axis, Y_mag);
title( ' Frequency Domain');


        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            if isempty(app.filteredSignal)
        uialert(app.UIFigure, 'No filtered signal available.', 'Error');
        return;
    end
    sound(app.filteredSignal, app.fs);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BrowseAudioButton = uibutton(app.UIFigure, 'push');
            app.BrowseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAudioButtonPushed, true);
            app.BrowseAudioButton.Text = 'Browse Audio';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.SelectChannelDropDownLabel = uilabel(app.UIFigure);
            app.SelectChannelDropDownLabel.Text = 'Select Channel';
            app.SelectChannelDropDown = uidropdown(app.UIFigure);
            app.SelectChannelDropDown.Items = {'Delta (No Effect)', 'Exp: e^(-2*pi*5000*t)', 'Exp: e^(-2*pi*1000*t)', 'Sinc: sinc(2*3000*t)', 'Custom Channel'};
            app.SelectChannelDropDown.Value = 'Delta (No Effect)';
            app.ApplyChannelButton = uibutton(app.UIFigure, 'push');
            app.ApplyChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChannelButtonPushed, true);
            app.ApplyChannelButton.Text = 'Apply Channel';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, '   Frequency Domain')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.NoiseStandardDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseStandardDeviationEditFieldLabel.Text = 'Noise Standard Deviation ';
            app.NoiseStandardDeviationEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise ';
            app.PlayNoisyAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayNoisyAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoisyAudioButtonPushed, true);
            app.PlayNoisyAudioButton.Text = 'Play Noisy Audio';
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Time Domain')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, '  Frequency Domain')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.ApplyLowPassFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyLowPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLowPassFilterButtonPushed, true);
            app.ApplyLowPassFilterButton.Text = 'Apply Low-Pass Filter';
            app.PlayFilteredAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio ';
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Time Domain')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, '   Frequency Domain')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Time Domain')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = AudioSignallll

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end